classdef MAPPERapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClassifyinterveinsButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        WingareaTextAreaLabel           matlab.ui.control.Label
        WingareaTextArea                matlab.ui.control.TextArea
        TrichomedensityTextAreaLabel    matlab.ui.control.Label
        TrichomedensityTextArea         matlab.ui.control.TextArea
        UIAxes3                         matlab.ui.control.UIAxes
        LoadRawImageButton              matlab.ui.control.Button
        LoadSegmentationMaskButton      matlab.ui.control.Button
        APaxisButton                    matlab.ui.control.Button
        dL3L4Button                     matlab.ui.control.Button
        PDaxisButton                    matlab.ui.control.Button
        sizeofwingfilterEditFieldLabel  matlab.ui.control.Label
        sizeofwingfilterEditField       matlab.ui.control.NumericEditField
        sizeofinterveinfilterEditFieldLabel  matlab.ui.control.Label
        sizeofinterveinfilterEditField  matlab.ui.control.NumericEditField
        ThresholddistancetrichomeEditFieldLabel  matlab.ui.control.Label
        ThresholddistancetrichomeEditField  matlab.ui.control.NumericEditField
        LabelnameEditFieldLabel         matlab.ui.control.Label
        LabelnameEditField              matlab.ui.control.EditField
        NumberofharmonicsEditFieldLabel  matlab.ui.control.Label
        NumberofharmonicsEditField      matlab.ui.control.NumericEditField
        BatchProcessingButton           matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        InputParameterLabel             matlab.ui.control.Label
        GlobalstatisticsLabel           matlab.ui.control.Label
        UseroperationLabel              matlab.ui.control.Label
        BiologicalaxesLabel             matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        NumericaloutputLabel            matlab.ui.control.Label
        IIndividualWingAnalysisLabel    matlab.ui.control.Label
        IIBatchprocessingLabel          matlab.ui.control.Label
        conversionfactorEditFieldLabel  matlab.ui.control.Label
        conversionfactorEditField       matlab.ui.control.NumericEditField
        UseroperationLabel_2            matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        MAPPERLabel                     matlab.ui.control.Label
        MulticellularAnalysisPlatformforLabel  matlab.ui.control.Label
        EngineeringResearchLabel        matlab.ui.control.Label
        forimagingat4xLabel             matlab.ui.control.Label
        forimagingat4xLabel_2           matlab.ui.control.Label
        forimagingat4xLabel_3           matlab.ui.control.Label
        between1020Label                matlab.ui.control.Label
        micronsperpixelLabel            matlab.ui.control.Label
        IndividualandbatchLabel         matlab.ui.control.Label
        IndividualandbatchLabel_2       matlab.ui.control.Label
        IndividualandbatchLabel_3       matlab.ui.control.Label
        IndividualandbatchLabel_4       matlab.ui.control.Label
        IndividualLabel                 matlab.ui.control.Label
        BatchLabel                      matlab.ui.control.Label
        HitszscoreLabel                 matlab.ui.control.Label
        WingpreprocessingLabel          matlab.ui.control.Label
        InputdirectoryEditFieldLabel    matlab.ui.control.Label
        InputdirectoryEditField         matlab.ui.control.EditField
        RemovewinghingeButton           matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FullProcessButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % FH EDIT: Startup function to allow demo and assure correct
           % paths are called before implementation
           
           % ADD FOLDERS AND SUBFOLDERS NEEDED ON STARTUP
            addpath(genpath('dependencies')); 
            addpath(genpath('demo_hingeRemoval_Uncropped'));
            addpath(genpath('demo_batchProcessing'));
            addpath(genpath('demo_individualProcessing'));
            addpath(genpath('userData'));
            
           % RUN A SAMPLE IMAGE
            rawImage = imread("demo_individualProcessing\57151_1_rotated.tif");
            imshow(rawImage,'Parent',app.UIAxes);
            save('rawImage.mat','rawImage');
            segmentationMask = imread("demo_individualProcessing\57151_1_rotated_Simple Segmentation.png"); 
            save('segmentationMask.mat','segmentationMask');
            ClassifyinterveinsButtonPushed(app, @ClassifyinterveinsButtonPushed);
            APaxisButtonPushed(app, @APaxisButtonPushed);
        end

        % Button pushed function: ClassifyinterveinsButton
        function ClassifyinterveinsButtonPushed(app, event)
            %clear;
            %clc;
            %workspace;  
            format long g;
            format compact;
            addpath('dependencies')
            
            load('rawImage.mat')
            load('segmentationMask.mat')
            
            
            %% Parameters
            morphFilterSizeIntervein = app.sizeofinterveinfilterEditField.Value;
            morphFilterSizwWing = app.sizeofwingfilterEditField.Value;
            thresholdTrichome = app.ThresholddistancetrichomeEditField.Value;
            magWing = app.conversionfactorEditField.Value;
            windowSizeDensityPlot = 32;
            labelName = app.LabelnameEditField.Value;

            %% Reading in raw image and segmentation mask
            % rawImage = imread(uigetfile('*.*'));
            %segmentationMask = imread(uigetfile('*.*'));
            %rawImage = imread('57151_1_rotated.tif');
            %segmentationMask = imread('57151_1_rotated_Simple Segmentation.png');

            %% Morphological operation to filter the segmentation mask of interveins
            % Function details: interveinMorphFilter(arg1, arg2);
            % arg1: segmentation mask, arg2: radius of morphological structuring
            % element for erosion and dilation
            [bwLabelIntervein] = interveinMorphFilter(segmentationMask, morphFilterSizeIntervein);

            %% Morphological operation to filter the overall wing blade area
            [bwWing, bwLabelWing] = wingMorphFilter(segmentationMask, morphFilterSizwWing);
            % Function details: wingMorphFilter(arg1, arg2);
            % arg1: segmentation mask, arg2: radius of morphological structuring
            % element for erosion and dilation

            %% Classification of interveins using the geometrical features
            [bwLabelNew, yfit] = classificationInterveinGeometric(bwLabelIntervein);
            % Printing the labelled wing image
            imwrite(label2rgb(bwLabelNew),labelName)
            save('bwLabelNew.mat','bwLabelNew')


            %% Area of interveins and wing blade area
            %[o1, o2, o3] = statsAreaTrichome(i1, i2, i3);
            % Output:
            % a) o1: area of individual labelled intervein regions
            % b) o2: trichomedensity of each individual intervein region
            % c) o3: spatial location of each intervein
            % Input:
            % a) i1: raw image data
            % b) i2: labelled interveins 
            % c) i3: thresholding filter of individual trichomes
            [areaIntervein, trichomeIntervein, hairSpace] = statsAreaTrichome(rawImage, bwLabelNew, thresholdTrichome);
            trichomeDensityIntervein = trichomeIntervein' ./ areaIntervein; 
            
            % Global statistics of wing blade
            statsCompleteWing = regionprops(bwWing,'Area');
            wingBladeArea = cat(1,statsCompleteWing.Area);
            trichomeDensityWing = sum(trichomeIntervein) / (magWing*magWing*(sum(areaIntervein)));
            
            app.WingareaTextArea.Value = num2str(wingBladeArea*magWing*magWing)
            app.TrichomedensityTextArea.Value = num2str(trichomeDensityWing)
            
            % Creating table of properties
            sortedyfit = sort(yfit);
            T = table(sortedyfit,areaIntervein*magWing*magWing,trichomeIntervein','VariableNames',{'ID','Area','# Trichomes'} )
            app.UITable.Data = T
            
            % Plotting heatmap of intervein area
            areaLabel = bwLabelNew;
            for i = 1: length(yfit)
                areaLabel(areaLabel == yfit(i)) = areaIntervein(yfit(i));
            end
            
            imshow(label2rgb(bwLabelNew),'Parent',app.UIAxes2)
            % imagesc(areaLabel,'Parent',app.UIAxes)
            % c = colorbar(app.UIAxes);
        end

        % Button pushed function: LoadRawImageButton
        function LoadRawImageButtonPushed(app, event)
            [rawImage, imagePath] = uigetfile('*.*');
            fullFilePath = fullfile(imagePath, rawImage);
            rawImage = imread(fullFilePath);
            imshow(rawImage,'Parent',app.UIAxes);
            save('rawImage.mat','rawImage');  
        end

        % Button pushed function: LoadSegmentationMaskButton
        function LoadSegmentationMaskButtonPushed(app, event)
            [segmentationMask, imagePath] = uigetfile('*.*');
            fullFilePath = fullfile(imagePath, segmentationMask);
            segmentationMask = imread(fullFilePath);
            save('segmentationMask.mat','segmentationMask'); 
        end

        % Button pushed function: APaxisButton
        function APaxisButtonPushed(app, event)
            addpath('dependencies')
            
            load('rawImage.mat')
            load('bwLabelNew.mat')
            hold(app.UIAxes3, "off"); % FH EDIT
            app.UIAxes3.cla; % FH EDIT
            
            [veinPositions] = veinNormal(bwLabelNew);
            magWing = app.conversionfactorEditField.Value;
            % Calculating veinPositions of the L1 and L4 vein for estimating the AP
            % axis
            centroidLabelStr = regionprops(bwLabelNew,'centroid');
            centroidLabel = cat(1,centroidLabelStr.Centroid);
            centroidI3 = centroidLabel(3,:);
            
            L2Points1 = [veinPositions(1,1) veinPositions(1,2)];
            L2Points2 = [veinPositions(1,3) veinPositions(1,4)];
            L5Points1 = [veinPositions(4,1) veinPositions(4,2)];
            L5Points2 = [veinPositions(4,3) veinPositions(4,4)];
            
            if norm(centroidI3 - L2Points1) > norm(centroidI3 - L2Points2)
                L2PointAP = L2Points1;
            else
                L2PointAP = L2Points2;
            end

            if norm(centroidI3 - L5Points1) > norm(centroidI3 - L5Points2)
                L5PointAP = L5Points1;
            else
                L5PointAP = L5Points2;
            end
            
            APPoints = vertcat(L2PointAP,L5PointAP);
            
            % Finding the length of AP axis
            lengthAP = norm(L2PointAP-L5PointAP);
            app.EditField.Value = num2str(lengthAP*magWing)
            
            % Plotting the AP axis
            imshow(rawImage,'Parent',app.UIAxes3)
            hold(app.UIAxes3)
            plot(app.UIAxes3, APPoints(:,1), APPoints(:,2),'--rs',...
                'LineWidth',2,...
                'MarkerSize',10,...
                'MarkerEdgeColor','b',...
                'MarkerFaceColor',[0.5,0.5,0.5])
        end

        % Button pushed function: dL3L4Button
        function dL3L4ButtonPushed(app, event)
            addpath('dependencies')
            
            load('rawImage.mat')
            load('bwLabelNew.mat')
            hold(app.UIAxes3, "off"); % FH EDIT
            app.UIAxes3.cla; % FH EDIT

            
            [veinPositions] = veinNormal(bwLabelNew);
            magWing = app.conversionfactorEditField.Value;
            
            centroidLabelStr = regionprops(bwLabelNew,'centroid');
            centroidLabel = cat(1,centroidLabelStr.Centroid);
            centroidI3 = centroidLabel(3,:);            
            
            
            L3Points1 = [veinPositions(2,1) veinPositions(2,2)];
            L3Points2 = [veinPositions(2,3) veinPositions(2,4)];
            L4Points1 = [veinPositions(3,1) veinPositions(3,2)];
            L4Points2 = [veinPositions(3,3) veinPositions(3,4)];
            
            
            if norm(centroidI3 - L3Points1) > norm(centroidI3 - L3Points2)
                L3PointRight = L3Points1;
                L3PointLeft = L3Points2;
            else
                L3PointRight = L3Points2;
                L3PointLeft = L3Points1;
            end
            
            if norm(centroidI3 - L4Points1) > norm(centroidI3 - L4Points2)
                L4PointRight = L4Points1;
                L4PointLeft = L4Points2;
            else
                L4PointRight = L4Points2;
                L4PointLeft = L4Points1;
            end
            
            L3L4PointsRight = vertcat(L3PointRight,L4PointRight);
            
            % Distance between L3 and L4
            lengthL3L4 = norm(L3PointRight - L4PointRight);
            app.EditField.Value = num2str(lengthL3L4*magWing)
            
            % Visualizing the L3 L4 measure taken
            imshow(rawImage,'Parent',app.UIAxes3)
            hold(app.UIAxes3)
            plot(app.UIAxes3,L3L4PointsRight(:,1), L3L4PointsRight(:,2),'--rs',...
                'LineWidth',2,...
                'MarkerSize',10,...
                'MarkerEdgeColor','b',...
                'MarkerFaceColor',[0.5,0.5,0.5])

        end

        % Button pushed function: PDaxisButton
        function PDaxisButtonPushed(app, event)
            addpath('dependencies')
            
            load('rawImage.mat')
            load('bwLabelNew.mat')
            hold(app.UIAxes3, "off"); % FH EDIT
            app.UIAxes3.cla; % FH EDIT
            
            [veinPositions] = veinNormal(bwLabelNew); 
            magWing = app.conversionfactorEditField.Value;

            centroidLabelStr = regionprops(bwLabelNew,'centroid');
            centroidLabel = cat(1,centroidLabelStr.Centroid);
            centroidI3 = centroidLabel(3,:);            
            
            
            L3Points1 = [veinPositions(2,1) veinPositions(2,2)];
            L3Points2 = [veinPositions(2,3) veinPositions(2,4)];
            L4Points1 = [veinPositions(3,1) veinPositions(3,2)];
            L4Points2 = [veinPositions(3,3) veinPositions(3,4)];
            
            
            if norm(centroidI3 - L3Points1) > norm(centroidI3 - L3Points2)
                L3PointRight = L3Points1;
                L3PointLeft = L3Points2;
            else
                L3PointRight = L3Points2;
                L3PointLeft = L3Points1;
            end
            
            if norm(centroidI3 - L4Points1) > norm(centroidI3 - L4Points2)
                L4PointRight = L4Points1;
                L4PointLeft = L4Points2;
            else
                L4PointRight = L4Points2;
                L4PointLeft = L4Points1;
            end
            
            L3L4PointsRight = vertcat(L3PointRight,L4PointRight);            
            L3L4PointsLeft = vertcat(L3PointLeft,L4PointLeft);

            pointsPD = vertcat(mean(L3L4PointsLeft),mean(L3L4PointsRight));
            
            % Length of PD axis
            lengthPD = norm( (mean(L3L4PointsRight)) - (mean(L3L4PointsLeft)));
            app.EditField.Value = num2str(lengthPD*magWing)
            
            % Visualizing the PD axis
            
            imshow(rawImage,'Parent',app.UIAxes3)
            hold(app.UIAxes3)
            plot(app.UIAxes3,pointsPD(:,1), pointsPD(:,2),'--rs',...
                'LineWidth',2,...
                'MarkerSize',10,...
                'MarkerEdgeColor','b',...
                'MarkerFaceColor',[0.5,0.5,0.5])
        end

        % Button pushed function: BatchProcessingButton
        function BatchProcessingButtonPushed(app, event)
            format long g;
            format compact;
            addpath('dependencies');
            addpath(genpath('userData'));
            
            magWing = app.conversionfactorEditField.Value;
            %% Parameter definition
            morphFilterSizeIntervein = app.sizeofinterveinfilterEditField.Value; % 5
            morphFilterSizwWing = app.sizeofwingfilterEditField.Value; % 8
            thresholdTrichome = app.ThresholddistancetrichomeEditField.Value; %2
            numHarmonics = app.NumberofharmonicsEditField.Value; %20 
            
            %% Getting user define path for the folders containing the wing images to be analyzed
            start_path = fullfile(matlabroot, 'C:\Users\Nilay\Desktop\AutomatedWingAnalysis\GPCR\Galphasubunits');
            topLevelFolder = uigetdir(start_path);
            if topLevelFolder == 0
            	return;
            end
            allSubFolders = genpath(topLevelFolder);
            remain = allSubFolders;
            listOfFolderNames = {};
            while true
            	[singleSubFolder, remain] = strtok(remain, ';');
            	if isempty(singleSubFolder)
            		break;
            	end
            	listOfFolderNames = [listOfFolderNames singleSubFolder];
            end
            numberOfFolders = length(listOfFolderNames)
            
            %% Looping through each folder and associated wing images for feature extraction
            
            wingAnalysisCounter = 1;
            folderAnalysisCounter = 1;
            
            % looping through the folders containing wing images
            for k = 1 : numberOfFolders
            	thisFolder = listOfFolderNames{k};
            	fprintf('Processing folder %s\n', thisFolder);
             	filePattern = sprintf('%s/*.tif', thisFolder);
                baseFileNames = dir(filePattern);
               	numberOfImageFiles = length(baseFileNames);
             	if numberOfImageFiles >= 1       
                    % looping through each file present in an individual folder
             		for f = 1 : numberOfImageFiles
             			fullFileName = fullfile(thisFolder, baseFileNames(f).name);
                        [filepath,name,ext] = fileparts(fullFileName);
                        
                        % Getting the file name without the extension
                        fullFileName2 = fullFileName(1:end-4);
                        
                        % Creating filenames for the segmentation mask
                        fullFileNameMask = strcat(fullFileName2,'_Simple Segmentation.png');
                        
                        % Creating filenames for the labelled interveins
                        fullFileNameLabel = strcat(fullFileName2,'_Label.jpg');
                        
                        % Reading in the images and corresponding segmentation masks
                        rawImage = imread(fullFileName);
                        segmentationMask = imread(fullFileNameMask);
                        
                        % Morphometric filter for filtering the interveins
                        [bwLabelIntervein] = interveinMorphFilter(segmentationMask, morphFilterSizeIntervein);
                        
                        % Morphometric filter for extracting the global wing shape
                        [bwWing, bwLabelWing] = wingMorphFilter(segmentationMask, morphFilterSizwWing);
                        
                        % Classifying the interveins
                        dummyLabel = [];
                        dummyLabel = bwLabelIntervein;
                        yfit = [];
                        [bwLabelNew, yfit] = classificationInterveinGeometric(dummyLabel);
                        
                        % Printing the labelled intervein areas as a file
                        imwrite(label2rgb(bwLabelNew),fullFileNameLabel);
                        
                        % Storing the filenames and genotypes of each individual wing
                        filesName{wingAnalysisCounter} = strcat(name);
                        folderName(wingAnalysisCounter) = folderAnalysisCounter;
                        
                        % Area and trichome numbers of individual intervein regions
                        areaIntervein = [];
                        trichomeIntervein = [];
                        hairSpace = [];
                        [areaIntervein, trichomeIntervein, hairSpace] = statsAreaTrichome(rawImage, bwLabelIntervein, thresholdTrichome);
                        statsAreaIntervein(wingAnalysisCounter,1:10) = 0;
                        statsTrichomeIntervein(wingAnalysisCounter,1:10) = 0;
                        for i = 1:max(max(bwLabelIntervein))
                            statsAreaIntervein(wingAnalysisCounter,yfit(i)) = areaIntervein(i);
                        end
                        for i = 1:max(max(bwLabelIntervein))
                            statsTrichomeIntervein(wingAnalysisCounter,yfit(i)) = trichomeIntervein(i);
                        end
                        
                        % Global statistics of wing
                        statsCompleteWing = [];
                        statsCompleteWing = regionprops(bwWing,'Area');
                        statswingBladeArea(wingAnalysisCounter) = cat(1,statsCompleteWing.Area);
                        statstrichomeDensityWing(wingAnalysisCounter) = sum(trichomeIntervein) / sum(areaIntervein);
                        
                        % Extracting elliptic fourier descriptor  coefficients 
                        EFDCoefficients{wingAnalysisCounter} = efdCoeffWing(bwWing, numHarmonics);
                        statsEFDReshaped(wingAnalysisCounter,:) = reshape(EFDCoefficients{wingAnalysisCounter},[1 numHarmonics*4]);
            
                        
                        % Extracting AP, PD nad d(L3,L4) for normal looking wings
                        yfitReshaped = [];
                        yfitReshaped = reshape(yfit,[1,length(yfit)]);
                        checkArray = [1,2,3,4,5,6,7];
                        if isequal(sort(yfitReshaped), checkArray) == 1
                            veinPositions = [];
                            % Extracting vein position
                            [veinPositions] = veinNormal(bwLabelNew);
                            % Extract intervein 3 centroid for identication of distal
                            % and proximal end of veins
                            centroidLabelStr = [];
                            centroidLabel = [];
                            centroidLabelStr = regionprops(bwLabelNew,'centroid');
                            centroidLabel = cat(1,centroidLabelStr.Centroid);
                            centroidI3 = centroidLabel(3,:);
                            
                            % calculating AP distance
                            L2Points1 = [veinPositions(1,1) veinPositions(1,2)];
                            L2Points2 = [veinPositions(1,3) veinPositions(1,4)];
                            L5Points1 = [veinPositions(4,1) veinPositions(4,2)];
                            L5Points2 = [veinPositions(4,3) veinPositions(4,4)];
                            if norm(centroidI3 - L2Points1) > norm(centroidI3 - L2Points2)
                                L2PointAP = L2Points1;
                            else
                                L2PointAP = L2Points2;
                            end
            
                            if norm(centroidI3 - L5Points1) > norm(centroidI3 - L5Points2)
                                L5PointAP = L5Points1;
                            else
                                L5PointAP = L5Points2;
                            end
                            lengthAP(wingAnalysisCounter) = norm(L2PointAP-L5PointAP);
                           
                            
                            % calculating distance between L3 and L4          
                            L3Points1 = [veinPositions(2,1) veinPositions(2,2)];
                            L3Points2 = [veinPositions(2,3) veinPositions(2,4)];
                            L4Points1 = [veinPositions(3,1) veinPositions(3,2)];
                            L4Points2 = [veinPositions(3,3) veinPositions(3,4)];
                            if norm(centroidI3 - L3Points1) > norm(centroidI3 - L3Points2)
                                L3PointRight = L3Points1;
                                L3PointLeft = L3Points2;
                            else
                                L3PointRight = L3Points2;
                                L3PointLeft = L3Points1;
                            end
            
                            if norm(centroidI3 - L4Points1) > norm(centroidI3 - L4Points2)
                                L4PointRight = L4Points1;
                                L4PointLeft = L4Points2;
                            else
                                L4PointRight = L4Points2;
                                L4PointLeft = L4Points1;
                            end
                            lengthL3L4(wingAnalysisCounter) = norm(L3PointRight - L4PointRight);
                            
                            % calculating PD distance                
                            L3L4PointsRight = vertcat(L3PointRight,L4PointRight);
                            L3L4PointsLeft = vertcat(L3PointLeft,L4PointLeft);
                            lengthPD(wingAnalysisCounter) = norm( (mean(L3L4PointsRight)) - (mean(L3L4PointsLeft)));
                        else
                            lengthAP(wingAnalysisCounter) = 0;
                            lengthPD(wingAnalysisCounter) = 0;
                            lengthL3L4(wingAnalysisCounter) = 0;
                        end
                        
                        % increaing the counter of processing wing image files
                        wingAnalysisCounter = wingAnalysisCounter+1;
                        fprintf('Processing image file %s\n', fullFileName);
             		end
             	else
             		fprintf('     Folder %s has no image files in it.\n', thisFolder);
                end
                folderAnalysisCounter = folderAnalysisCounter + 1;
            end
            
            % Creating table with all the features
            varNames = {'Wing name','Genotype','Intervein area','Trichome density Intervein','Wing area', 'Total Trichome density','AP axis', 'PD axis', 'd(L3-L4)'};
            T = table(filesName',folderName',statsAreaIntervein,statsTrichomeIntervein,statswingBladeArea',statstrichomeDensityWing',lengthAP',lengthPD' , lengthL3L4','VariableNames',varNames);
            save('T.mat','T');
            
            % Creating bar plot for global area comparision
            for i = 2:max(folderName)
                dummyGenotypeArea = [];
                dummyCounter = 1;
                     for j = 1:length(folderName)
                        if folderName(j) == i
                         dummyGenotypeArea(dummyCounter) =  magWing*magWing*statswingBladeArea(j);
                            dummyCounter = dummyCounter + 1;
                         end
                     end
                areaGenotype(i-1) = mean(dummyGenotypeArea); 
            end
            b = bar(app.UIAxes4,areaGenotype);
            N = size(statsEFDReshaped,2);
            C = cell(N,1);
            for i = 1:N
                C{i} = ['Coefficient' num2str(i)];
            end
            EFDNames = table2cell([{'Wing name', 'Genotype'}, cell2table(C')]);
            statsEFDReshaped = cell2table([filesName' table2cell(array2table(folderName))' table2cell(array2table(statsEFDReshaped))],'VariableNames',EFDNames);
            save('EFDBatch.mat', 'statsEFDReshaped');
            EFDBatch = load('EFDBatch.mat');
            writetable(EFDBatch.statsEFDReshaped, 'EFD_Batch_Coefficients.csv');
            writetable(T, 'Wing_Measurements_Batch_Output.csv');
            fprintf('\n');
            disp("%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%");
            disp("Batch processing complete!");
        end

        % Button pushed function: RemovewinghingeButton
        function RemovewinghingeButtonPushed(app, event)
            % FH EDIT: Allow remove wing hinge functionality
            myFolder = app.InputdirectoryEditField.Value;
            WingHingeRemoval;
        end

        % Button pushed function: FullProcessButton
        function FullProcessButtonPushed(app, event)
            [rawImage, imagePath] = uigetfile('*.*');
            fullFilePath = fullfile(imagePath, rawImage);
            rawImage = imread(fullFilePath);
            imshow(rawImage,'Parent',app.UIAxes);
            save('rawImage.mat','rawImage');  
            segmentationMask = imread(strcat(fullFilePath(1:end-4),'_Simple Segmentation.png'));  
            save('segmentationMask.mat','segmentationMask');   
            ClassifyinterveinsButtonPushed(app, @ClassifyinterveinsButtonPushed);
            APaxisButtonPushed(app, @APaxisButtonPushed);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ClassifyinterveinsButton = uibutton(app.UIFigure, 'push');
            app.ClassifyinterveinsButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyinterveinsButtonPushed, true);
            app.ClassifyinterveinsButton.Text = 'Classify interveins';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Labelled Wing image')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ID'; 'Area'; '# Trichomes'; 'Column 4'};
            app.UITable.RowName = {};
            app.WingareaTextAreaLabel = uilabel(app.UIFigure);
            app.WingareaTextAreaLabel.Text = 'Wing area';
            app.WingareaTextArea = uitextarea(app.UIFigure);
            app.TrichomedensityTextAreaLabel = uilabel(app.UIFigure);
            app.TrichomedensityTextAreaLabel.Text = 'Trichome density:';
            app.TrichomedensityTextArea = uitextarea(app.UIFigure);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Biological axes display')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.LoadRawImageButton = uibutton(app.UIFigure, 'push');
            app.LoadRawImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawImageButtonPushed, true);
            app.LoadRawImageButton.Text = 'Load Raw Image';
            app.LoadSegmentationMaskButton = uibutton(app.UIFigure, 'push');
            app.LoadSegmentationMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentationMaskButtonPushed, true);
            app.LoadSegmentationMaskButton.Text = 'Load Segmentation Mask';
            app.APaxisButton = uibutton(app.UIFigure, 'push');
            app.APaxisButton.ButtonPushedFcn = createCallbackFcn(app, @APaxisButtonPushed, true);
            app.APaxisButton.Text = 'AP axis';
            app.dL3L4Button = uibutton(app.UIFigure, 'push');
            app.dL3L4Button.ButtonPushedFcn = createCallbackFcn(app, @dL3L4ButtonPushed, true);
            app.dL3L4Button.Text = 'd(L3, L4)';
            app.PDaxisButton = uibutton(app.UIFigure, 'push');
            app.PDaxisButton.ButtonPushedFcn = createCallbackFcn(app, @PDaxisButtonPushed, true);
            app.PDaxisButton.Text = 'PD axis';
            app.sizeofwingfilterEditFieldLabel = uilabel(app.UIFigure);
            app.sizeofwingfilterEditFieldLabel.Text = 'size of wing filter';
            app.sizeofwingfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.sizeofwingfilterEditField.Value = 8;
            app.sizeofinterveinfilterEditFieldLabel = uilabel(app.UIFigure);
            app.sizeofinterveinfilterEditFieldLabel.Text = 'size of intervein filter';
            app.sizeofinterveinfilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.sizeofinterveinfilterEditField.Value = 5;
            app.ThresholddistancetrichomeEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholddistancetrichomeEditFieldLabel.Text = 'Threshold distance trichome';
            app.ThresholddistancetrichomeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholddistancetrichomeEditField.Value = 2;
            app.LabelnameEditFieldLabel = uilabel(app.UIFigure);
            app.LabelnameEditFieldLabel.Text = 'Label name';
            app.LabelnameEditField = uieditfield(app.UIFigure, 'text');
            app.LabelnameEditField.Value = 'imageOutput.png';
            app.NumberofharmonicsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofharmonicsEditFieldLabel.Text = 'Number of harmonics';
            app.NumberofharmonicsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofharmonicsEditField.Value = 15;
            app.BatchProcessingButton = uibutton(app.UIFigure, 'push');
            app.BatchProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessingButtonPushed, true);
            app.BatchProcessingButton.Text = 'Batch Processing';
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Average area ')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.InputParameterLabel = uilabel(app.UIFigure);
            app.InputParameterLabel.Text = 'Input Parameter ';
            app.GlobalstatisticsLabel = uilabel(app.UIFigure);
            app.GlobalstatisticsLabel.Text = 'Global statistics';
            app.UseroperationLabel = uilabel(app.UIFigure);
            app.UseroperationLabel.Text = 'User operation';
            app.BiologicalaxesLabel = uilabel(app.UIFigure);
            app.BiologicalaxesLabel.Text = 'Biological axes';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.NumericaloutputLabel = uilabel(app.UIFigure);
            app.NumericaloutputLabel.Text = 'Numerical output';
            app.IIndividualWingAnalysisLabel = uilabel(app.UIFigure);
            app.IIndividualWingAnalysisLabel.Text = 'I   Individual Wing Analysis';
            app.IIBatchprocessingLabel = uilabel(app.UIFigure);
            app.IIBatchprocessingLabel.Text = 'II    Batch processing';
            app.conversionfactorEditFieldLabel = uilabel(app.UIFigure);
            app.conversionfactorEditFieldLabel.Text = 'conversion factor';
            app.conversionfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.conversionfactorEditField.Value = 0.002;
            app.UseroperationLabel_2 = uilabel(app.UIFigure);
            app.UseroperationLabel_2.Text = 'User operation';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.MAPPERLabel = uilabel(app.UIFigure);
            app.MAPPERLabel.Text = 'MAPPER';
            app.MulticellularAnalysisPlatformforLabel = uilabel(app.UIFigure);
            app.MulticellularAnalysisPlatformforLabel.Text = 'Multicellular Analysis Platform for';
            app.EngineeringResearchLabel = uilabel(app.UIFigure);
            app.EngineeringResearchLabel.Text = 'Engineering Research';
            app.forimagingat4xLabel = uilabel(app.UIFigure);
            app.forimagingat4xLabel.Text = '8 for imaging at 4x';
            app.forimagingat4xLabel_2 = uilabel(app.UIFigure);
            app.forimagingat4xLabel_2.Text = '5 for imaging at 4x';
            app.forimagingat4xLabel_3 = uilabel(app.UIFigure);
            app.forimagingat4xLabel_3.Text = '2 for imaging at 4x';
            app.between1020Label = uilabel(app.UIFigure);
            app.between1020Label.Text = 'between 10-20';
            app.micronsperpixelLabel = uilabel(app.UIFigure);
            app.micronsperpixelLabel.Text = 'microns per pixel';
            app.IndividualandbatchLabel = uilabel(app.UIFigure);
            app.IndividualandbatchLabel.Text = 'Individual and batch';
            app.IndividualandbatchLabel_2 = uilabel(app.UIFigure);
            app.IndividualandbatchLabel_2.Text = 'Individual and batch';
            app.IndividualandbatchLabel_3 = uilabel(app.UIFigure);
            app.IndividualandbatchLabel_3.Text = 'Individual and batch';
            app.IndividualandbatchLabel_4 = uilabel(app.UIFigure);
            app.IndividualandbatchLabel_4.Text = 'Individual and batch';
            app.IndividualLabel = uilabel(app.UIFigure);
            app.IndividualLabel.Text = 'Individual';
            app.BatchLabel = uilabel(app.UIFigure);
            app.BatchLabel.Text = 'Batch';
            app.HitszscoreLabel = uilabel(app.UIFigure);
            app.HitszscoreLabel.Text = 'Hits (z-score)';
            app.WingpreprocessingLabel = uilabel(app.UIFigure);
            app.WingpreprocessingLabel.Text = 'Wing preprocessing';
            app.InputdirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.InputdirectoryEditFieldLabel.Text = 'Input directory';
            app.InputdirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.InputdirectoryEditField.Value = 'demo_hingeRemoval_Uncropped\';
            app.RemovewinghingeButton = uibutton(app.UIFigure, 'push');
            app.RemovewinghingeButton.ButtonPushedFcn = createCallbackFcn(app, @RemovewinghingeButtonPushed, true);
            app.RemovewinghingeButton.Text = 'Remove wing hinge';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.FullProcessButton = uibutton(app.UIFigure, 'push');
            app.FullProcessButton.ButtonPushedFcn = createCallbackFcn(app, @FullProcessButtonPushed, true);
            app.FullProcessButton.Text = {'Full'; 'Process'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAPPERapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end